classdef hyperpref < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxesHV              matlab.ui.control.UIAxes
        InitializeButton      matlab.ui.control.Button
        RecalculateButton     matlab.ui.control.Button
        ResetSelectionButton  matlab.ui.control.Button
        UIAxesSelection       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d % domain configuration
        m % model configuration
        p % MAP-Elites configuration
        pm % POEM configuration
        DOMAIN % Domain description string
        
        currentSelection % array holding current selection before reapplying QD
        hypID % Hypersurface ID
        initialized
        
        maps % saved maps
        configs % configurations
        results % results
    end
    
    methods (Access = private)
        
        
        function saveResults(app,fileName)
            d = app.d; 
            m = app.m; 
            p = app.p; 
            pm = app.pm; 
            DOMAIN = app.DOMAIN;
            
            currentSelection = app.currentSelection;
            hypID = app.hypID;
            
            maps = app.maps; 
            configs = app.configs; 
            results = app.results; 
            
            save(fileName,'d','m','p','pm','DOMAIN','currentSelection','hypID','maps','configs','results');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.initialized = false;
            %% Configuration
            addpath(genpath(pwd))                           % Set path to all modules
            DOF = 16;app.DOMAIN = 'catmullRom';                 % Degrees of freedom, Catmull-Rom spline domain
            app.d = domain(DOF);                                % Domain configuration
            app.p = defaultParamSet;                            % Base Quality Diversity (QD) configuration (MAP-Elites)
            app.m = cfgLatentModel('data/workdir',app.d.resolution);% VAE configuration
            
            app.pm = poemParamSet(app.p,app.m);                    % Configure POEM ("Phenotypic niching based Optimization by Evolving a Manifold")
            app.pm.categorize = @(geno,pheno,p,d) predictFeatures(pheno,p.model);  % Anonymous function ptr to phenotypic categorization function (= VAE)
            
            %% Initialize Experiment
            % Initialize solution set using space filling Sobol sequence in genetic space
            sobSequence = scramble(sobolset(app.d.dof,'Skip',1e3),'MatousekAffineOwen');  sobPoint = 1;
            initSamples = range(app.d.ranges').*sobSequence(sobPoint:(sobPoint+app.pm.map.numInitSamples)-1,:)+app.d.ranges(:,1)';
            [fitness,polygons] = fitfun(initSamples,app.d);
            
            %% Run POEM's first iteration
            [app.maps{1}, app.configs{1}, app.results{1}] = poem(initSamples,polygons,fitness,app.pm,app.d,2);
            [genes,fitness,~,bins] = extractMap(app.results{1}.maps{1});
            colors = [zeros(size(genes,1),1) fitness zeros(size(genes,1),1)];            
            showPhenotype(genes, app.d, app.p.featureResolution(1), app.UIAxesHV, bins, colors);
            title(app.UIAxesHV,'1st Iteration Result');
            app.UIAxesHV.DataAspectRatio = [1 1 1];
            app.saveResults([app.DOMAIN '_step1.mat']);
            disp('HyperPref Step 1 Done');
            app.hypID = 1;
            app.currentSelection = [];
            app.initialized = true;

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown2(app, event)
            pos = event.Source.CurrentPoint;
            figPos = app.UIAxesHV.InnerPosition;
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    relPos = app.UIAxesHV.CurrentPoint(1,1:2);
                    polyAxes = app.UIAxesHV.Children;
                    polys = []; numVerts = 0;
                    % Find closest shape
                    minDist = 9999; closestShape = nan;
                    for i=1:length(polyAxes)
                        %polys = [polys;polyAxes(i).Shape.Vertices];
                        distToPoly = pdist2(relPos,polyAxes(i).Shape.Vertices);
                        distToPoly = min(distToPoly);
                        if distToPoly < minDist
                            minDist = distToPoly;
                            closestShape = length(polyAxes) - i + 1;
                        end
                    end
                    closestShapeVisualID = app.UIAxesHV.Children(closestShape).UserData.ID;
                    app.UIAxesHV.Children(closestShapeVisualID).EdgeColor = mod(app.UIAxesHV.Children(closestShapeVisualID).EdgeColor + 1,2);
                    if app.initialized
                        x = find(closestShape==app.currentSelection);
                        if isempty(x)
                            app.currentSelection(end+1) = closestShape;
                        else
                            app.currentSelection(x) = [];
                        end
                        disp(app.currentSelection)
                        
                        if ~isempty(app.currentSelection)
                            [genes] = extractMap(app.results{app.hypID}.maps{1});
                            showPhenotype(genes(app.currentSelection,:), app.d, 2, app.UIAxesSelection);
                            app.UIAxesSelection.DataAspectRatio = [1 1 1];
                        end
                        
                    end
                end
            end
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)
            
            [genes,~,features,~] = extractMap(app.results{app.hypID}.maps{1});
            app.d.selection.models{app.hypID} = app.results{app.hypID}.models; % Save user model to use as constraint model
            app.d.selection.selected{app.hypID} = features(app.currentSelection,:);
            app.d.selection.deselected{app.hypID} = features; app.d.selection.deselected{app.hypID}(app.currentSelection,:) = [];
            %disp(app.d.selection.selected{app.hypID})
            
            app.hypID = app.hypID + 1;
            newSamples = genes(app.currentSelection,:);
            nNewPerSelected = ceil(app.pm.map.numInitSamples./length(app.currentSelection));
            for i=1:length(app.currentSelection)
                newSampleMutations = app.pm.mutSelection * randn(nNewPerSelected,app.d.dof);
                newSamples = [newSamples; genes(app.currentSelection(i),:) + newSampleMutations];
            end
            [newSamplesfitness,newSamplespolygons] = fitfun(newSamples,app.d); % Recalculate fitness! (User selection influences fitness values)
            [app.maps{app.hypID}, app.configs{app.hypID}, app.results{app.hypID}] = poem(newSamples,newSamplespolygons,newSamplesfitness,app.pm,app.d,2);
            app.saveResults([app.DOMAIN '_step' int2str(app.hypID) '.mat']);
            
            [genes,fitness,~,bins] = extractMap(app.results{app.hypID}.maps{1});
            accepted = fitness > 0.1;
            colors = [zeros(sum(accepted),1) fitness(accepted) zeros(sum(accepted),1)];
            showPhenotype(genes(accepted,:), app.d, app.p.featureResolution(1), app.UIAxesHV, bins(accepted,:), colors);
            title(app.UIAxesHV,['Iteration Result' int2str(app.hypID)]);
            app.UIAxesHV.DataAspectRatio = [1 1 1];
            disp(['HyperPref Step ' int2str(app.hypID) ' Done']);
            app.currentSelection = [];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown2, true);
            app.UIAxesHV = uiaxes(app.UIFigure);
            title(app.UIAxesHV, 'Working Hypervolume')
            xlabel(app.UIAxesHV, '')
            ylabel(app.UIAxesHV, '')
            app.UIAxesHV.XTickLabel = '';
            app.UIAxesHV.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxesHV.YTickLabel = '';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.RecalculateButton = uibutton(app.UIFigure, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Text = 'Recalculate';
            app.ResetSelectionButton = uibutton(app.UIFigure, 'push');
            app.ResetSelectionButton.Text = 'Reset Selection';
            app.UIAxesSelection = uiaxes(app.UIFigure);
            title(app.UIAxesSelection, 'Selection')
            xlabel(app.UIAxesSelection, '')
            ylabel(app.UIAxesSelection, '')
            app.UIAxesSelection.XTickLabel = '';
            app.UIAxesSelection.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperpref

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end